classdef tumor_growth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tumor_GrowthUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        loc_2                           matlab.ui.control.NumericEditField
        InsideLabel                     matlab.ui.control.Label
        OutsideLabel                    matlab.ui.control.Label
        TicknessText                    matlab.ui.control.Label
        tickness                        matlab.ui.control.NumericEditField
        LocText                         matlab.ui.control.Label
        loc                             matlab.ui.control.NumericEditField
        delta                           matlab.ui.control.Label
        delta2                          matlab.ui.control.NumericEditField
        delta1                          matlab.ui.control.NumericEditField
        VesselShapeText                 matlab.ui.control.Label
        VesselShapeDropDown             matlab.ui.control.DropDown
        InfunctionofLocalizationLabel   matlab.ui.control.Label
        VesselsContributionCheckBox_2   matlab.ui.control.CheckBox
        InfunctionofProliferationrateLabel  matlab.ui.control.Label
        FunctionShapeText               matlab.ui.control.Label
        FunctionShapeDropDown           matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        VesselsContributionCheckBox     matlab.ui.control.CheckBox
        lim                             matlab.ui.control.Label
        y                               matlab.ui.control.Label
        lim2                            matlab.ui.control.NumericEditField
        lim1                            matlab.ui.control.NumericEditField
        y3                              matlab.ui.control.NumericEditField
        y2                              matlab.ui.control.NumericEditField
        y1                              matlab.ui.control.NumericEditField
        omega_text                      matlab.ui.control.Label
        alfa_text                       matlab.ui.control.Label
        omega                           matlab.ui.control.NumericEditField
        alfa                            matlab.ui.control.NumericEditField
        InitialCells                    matlab.ui.control.NumericEditField
        TimeStepdaysLabel_3             matlab.ui.control.Label
        XYMax                           matlab.ui.control.NumericEditField
        BoxDimensionLabel_2             matlab.ui.control.Label
        InitialRadius                   matlab.ui.control.NumericEditField
        TimeStepdaysLabel_2             matlab.ui.control.Label
        InitialTumorShapeDropDown       matlab.ui.control.DropDown
        InitialTumorShapeDropDownLabel  matlab.ui.control.Label
        TimeStep                        matlab.ui.control.NumericEditField
        TimeStepdaysLabel               matlab.ui.control.Label
        EndTime                         matlab.ui.control.NumericEditField
        EndTimemonthLabel               matlab.ui.control.Label
        BoxDimension                    matlab.ui.control.NumericEditField
        BoxDimensionLabel               matlab.ui.control.Label
        SimulationSettingsLabel         matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        PlayButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TumorGrowthTab                  matlab.ui.container.Tab
        graph1                          matlab.ui.control.UIAxes
        ProliferationStateTab           matlab.ui.container.Tab
        graph2                          matlab.ui.control.UIAxes
        SpacialVesselContribuitionTab   matlab.ui.container.Tab
        graph7                          matlab.ui.control.UIAxes
        MetabolicRadiusTab              matlab.ui.container.Tab
        graph5                          matlab.ui.control.UIAxes
        ProliferationAverageTab         matlab.ui.container.Tab
        graph6                          matlab.ui.control.UIAxes
        HOCTab                          matlab.ui.container.Tab
        graph3                          matlab.ui.control.UIAxes
        NHOCTab                         matlab.ui.container.Tab
        graph4                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        Results                         matlab.ui.control.TextArea
        SimulationResultsLabel          matlab.ui.control.Label
        dayLabel_2                      matlab.ui.control.Label
        cellscm3Label                   matlab.ui.control.Label
        dayLabel                        matlab.ui.control.Label
        day3Label                       matlab.ui.control.Label
        cm2dayLabel                     matlab.ui.control.Label
        pmaxText                        matlab.ui.control.NumericEditField
        KText                           matlab.ui.control.NumericEditField
        miuText                         matlab.ui.control.NumericEditField
        DpText                          matlab.ui.control.NumericEditField
        DcText                          matlab.ui.control.NumericEditField
        pmaxSlider                      matlab.ui.control.Slider
        mSliderLabel                    matlab.ui.control.Label
        KSlider                         matlab.ui.control.Slider
        KSliderLabel                    matlab.ui.control.Label
        miuSlider                       matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        DpSlider                        matlab.ui.control.Slider
        DpSliderLabel                   matlab.ui.control.Label
        DcSlider                        matlab.ui.control.Slider
        DcSliderLabel                   matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image1                          matlab.ui.control.Image
        DifferentialEquationConstantsLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = private)
        playState=0;
        stopValue=0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.omega.Visible="off";
            app.alfa_text.Visible="off";
            app.alfa.Visible="off";
            app.omega_text.Visible="off";
            % Initialization of Graph 1 and 2
            [dim,~]=correction_dimensions(app.BoxDimension.Value);
            box_initialization(dim,app.graph1,app.graph2,app.graph7);
            app.graph1.CLimMode="manual";
            app.graph2.CLimMode="manual";
        end

        % Value changing function: DcSlider
        function DcSliderValueChanging(app, event)
            app.DcText.Value=event.Value;
        end

        % Value changing function: DpSlider
        function DpSliderValueChanging(app, event)
            app.DpText.Value=event.Value;
        end

        % Value changing function: miuSlider
        function miuSliderValueChanging(app, event)
            app.miuText.Value=event.Value;
        end

        % Value changing function: KSlider
        function KSliderValueChanging(app, event)
            app.KText.Value=event.Value;
        end

        % Value changing function: pmaxSlider
        function pmaxSliderValueChanging(app, event)
            app.pmaxText.Value=event.Value;
        end

        % Value changed function: DcText
        function DcTextValueChanged(app, event)
            app.DcSlider.Value=app.DcText.Value;
        end

        % Value changed function: DpText
        function DpTextValueChanged(app, event)
            app.DpSlider.Value=app.DpText.Value;
        end

        % Value changed function: miuText
        function miuTextValueChanged(app, event)
            app.miuSlider.Value=app.miuText.Value;
        end

        % Value changed function: KText
        function KTextValueChanged(app, event)
            app.KSlider.Value=app.KText.Value;
        end

        % Value changed function: pmaxText
        function pmaxTextValueChanged(app, event)
            app.pmaxSlider.Value=app.pmaxText.Value;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.graph1.CLimMode="auto";
            app.graph1.YTick=[1,app.BoxDimension.Value+1];
            app.graph1.XTick=[1,app.BoxDimension.Value+1];
            app.graph2.YTick=[1,app.BoxDimension.Value+1];
            app.graph2.XTick=[1,app.BoxDimension.Value+1];

            if strcmp(app.InitialTumorShapeDropDown.Value,"Flowers")
                options_flowers=[app.alfa.Value,app.omega.Value];
                [dim,dxy,dp,u,distance]=initial_state(app.BoxDimension.Value,app.XYMax.Value,app.pmaxText.Value,app.InitialTumorShapeDropDown.Value,app.InitialRadius.Value,app.InitialCells.Value,options_flowers);
            else
                [dim,dxy,dp,u,distance]=initial_state(app.BoxDimension.Value,app.XYMax.Value,app.pmaxText.Value,app.InitialTumorShapeDropDown.Value,app.InitialRadius.Value,app.InitialCells.Value,[]);
            end

            time_list=(0:app.TimeStep.Value:app.EndTime.Value*30)/30;
            Rmet_list=zeros(size(time_list));
            paverage_list=zeros(size(time_list));
            hoc_list=zeros(size(time_list));
            nhoc_list=zeros(size(time_list));

            if (app.VesselsContributionCheckBox.Value==1)
                u2d_options=[app.lim1.Value,app.lim2.Value];
                gamma_options=[app.y1.Value,app.y2.Value,app.y3.Value];
            else
                u2d_options=[];
                gamma_options=[];
            end

            delta_options=ones(dim);
            if (app.VesselsContributionCheckBox_2.Value==1)
                if strcmp(app.VesselShapeDropDown.Value,"Circle")
                    c1=round(app.loc.Value/dxy);
                    c2=round(app.loc_2.Value/dxy);
                    for i=1:dim
                        for j=1:dim
                            vessels_distance(i,j)=sqrt(((i-c1)*dxy)^2+((j-c2)*dxy)^2);
                        end
                    end
                    max_dist=max(max(vessels_distance));
                    delta_options=app.delta2.Value.*(vessels_distance<=app.tickness.Value)+((app.delta2.Value-app.delta1.Value)*((max_dist-vessels_distance)/(max_dist-app.tickness.Value))+app.delta1.Value).*(vessels_distance>app.tickness.Value);
                
                elseif strcmp(app.VesselShapeDropDown.Value,"Horizontal Line")
                    dx=round((app.tickness.Value/dxy)/2);
                    delta_options(round(app.loc.Value/dxy)-dx:round(app.loc.Value/dxy)+dx,:)=app.delta2.Value;
                    delta_options(1:round(app.loc.Value/dxy)-dx,:)=ones(size(delta_options(1:round(app.loc.Value/dxy)-dx,:))).*linspace(app.delta1.Value,app.delta2.Value,size(delta_options(1:round(app.loc.Value/dxy)-dx,:),1))';
                    delta_options(round(app.loc.Value/dxy)+dx:end,:)=ones(size(delta_options(round(app.loc.Value/dxy)+dx:end,:))).*linspace(app.delta2.Value,app.delta1.Value,size(delta_options(round(app.loc.Value/dxy)+dx:end,:),1))';
                else
                    dy=round((app.tickness.Value/dxy)/2);
                    delta_options(:,round(app.loc.Value/dxy)-dy:round(app.loc.Value/dxy)+dy)=app.delta2.Value;
                    delta_options(:,1:round(app.loc.Value/dxy)-dy)=ones(size(delta_options(:,1:round(app.loc.Value/dxy)-dy))).*linspace(app.delta1.Value,app.delta2.Value,size(delta_options(:,1:round(app.loc.Value/dxy)-dy),2));
                    delta_options(:,round(app.loc.Value/dxy)+dy:end)=ones(size(delta_options(:,round(app.loc.Value/dxy)+dy:end))).*linspace(app.delta2.Value,app.delta1.Value,size(delta_options(:,round(app.loc.Value/dxy)+dy:end),2));
                end
            end
            if sum(sum(delta_options))~=dim*dim
                clim(app.graph7,[min(min(delta_options)),max(max(delta_options))]);
            end
            imagesc(delta_options,'Interpolation','bilinear','parent',app.graph7)

            for t=0:app.TimeStep.Value:app.EndTime.Value*30
                [u,u2D,prol_mean,R_met,p_average,hoc,nhoc]=newSimulation(dim,u,app.DcText.Value,app.DpText.Value,app.miuText.Value,app.KText.Value,dxy,dp,app.TimeStep.Value,distance,u2d_options,gamma_options,app.FunctionShapeDropDown.Value,app.VesselsContributionCheckBox_2.Value,delta_options);
                plot_u2D(u2D,app.graph1);
                pause(1e-9);
                plot_prolMean(prol_mean,app.graph2);
                Rmet_list((t/app.TimeStep.Value)+1)=R_met;
                paverage_list((t/app.TimeStep.Value)+1)=p_average;
                hoc_list(((t/app.TimeStep.Value)+1))=hoc;
                nhoc_list(((t/app.TimeStep.Value)+1))=nhoc;
                String1=".Máx nºcells: "+num2str(max(max(u2D)),'%.2e')+" cells/cm^3";
                String2=".Metabolic Radius: "+num2str(R_met, '%.4f')+" cm";
                String3=".Proliferation Average: "+num2str(p_average, '%.4f')+" /day";
                app.Results.Value=String1+"   "+String2+"   "+String3;
            end

            % Final Plots
            plot(time_list,hoc_list,'LineWidth',1.5,'Color','#0072BD','parent',app.graph3)
            xlim(app.graph3,[0,app.EndTime.Value])
            plot(time_list,nhoc_list,'LineWidth',1.5,'Color','#D95319','parent',app.graph4)
            xlim(app.graph4,[0,app.EndTime.Value])
            plot(time_list,Rmet_list,'LineWidth',1.5,'Color','#77AC30','parent',app.graph5)
            ylim(app.graph5,[0,max(Rmet_list)])
            xlim(app.graph5,[0,app.EndTime.Value])
            plot(time_list,paverage_list,'LineWidth',1.5,'Color','#EDB120','parent',app.graph6)
            ylim(app.graph6,[0,max(paverage_list)])
            xlim(app.graph6,[0,app.EndTime.Value])
 
        end

        % Value changed function: XYMax
        function XYMaxValueChanged(app, event)
            app.graph1.XTickLabel=[0,app.XYMax.Value];
            app.graph1.YTickLabel=[0,app.XYMax.Value];
            app.graph2.XTickLabel=[0,app.XYMax.Value];
            app.graph2.YTickLabel=[0,app.XYMax.Value];
        end

        % Value changed function: InitialTumorShapeDropDown
        function InitialTumorShapeDropDownValueChanged(app, event)
            if strcmp(app.InitialTumorShapeDropDown.Value,"Flowers")
                app.omega.Visible="on";
                app.alfa_text.Visible="on";
                app.alfa.Visible="on";
                app.omega_text.Visible="on";
            else
                app.omega.Visible="off";
                app.alfa_text.Visible="off";
                app.alfa.Visible="off";
                app.omega_text.Visible="off";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            imshow("images\info_img.png")
        end

        % Value changed function: VesselsContributionCheckBox
        function VesselsContributionCheckBoxValueChanged(app, event)
            if (app.VesselsContributionCheckBox.Value==1)
                app.y.Visible="on";
                app.y1.Visible="on";
                app.y2.Visible="on";
                app.y3.Visible="on";
                app.lim.Visible="on";
                app.lim1.Visible="on";
                app.lim2.Visible="on";
                app.FunctionShapeDropDown.Visible="on";
                app.FunctionShapeText.Visible="on";
            else
                app.y.Visible="off";
                app.y1.Visible="off";
                app.y2.Visible="off";
                app.y3.Visible="off";
                app.lim.Visible="off";
                app.lim1.Visible="off";
                app.lim2.Visible="off";
                app.FunctionShapeDropDown.Visible="off";
                app.FunctionShapeText.Visible="off";
            end
        end

        % Value changed function: VesselsContributionCheckBox_2
        function VesselsContributionCheckBox_2ValueChanged(app, event)
            if (app.VesselsContributionCheckBox_2.Value==1)
                app.VesselShapeText.Visible="on";
                app.VesselShapeDropDown.Visible="on";
                app.OutsideLabel.Visible="on";
                app.InsideLabel.Visible="on";
                app.delta.Visible="on";
                app.delta1.Visible="on";
                app.delta2.Visible="on";
                app.loc.Visible="on";
                app.tickness.Visible="on";
                app.LocText.Visible="on";
                app.TicknessText.Visible="on";
            else
                app.VesselShapeText.Visible="off";
                app.VesselShapeDropDown.Visible="off";
                app.OutsideLabel.Visible="off";
                app.InsideLabel.Visible="off";
                app.delta.Visible="off";
                app.delta1.Visible="off";
                app.delta2.Visible="off";
                app.loc.Visible="off";
                app.tickness.Visible="off";
                app.LocText.Visible="off";
                app.TicknessText.Visible="off";
            end
        end

        % Value changed function: VesselShapeDropDown
        function VesselShapeDropDownValueChanged(app, event)
            if strcmp(app.VesselShapeDropDown.Value,"Horizontal Line")
                app.LocText.Text="Y";
                app.loc.Limits=[0,app.XYMax.Value];
                app.loc.Value=7;
                app.TicknessText.Text="Tickness";
                app.tickness.Value=1;
                app.loc_2.Visible='off';
            elseif strcmp(app.VesselShapeDropDown.Value,"Vertical Line")
                app.LocText.Text="X";
                app.loc.Limits=[0,app.XYMax.Value];
                app.loc.Value=7;
                app.TicknessText.Text="Tickness";
                app.tickness.Value=1;
                app.loc_2.Visible='off';
            else 
                app.LocText.Text="Center";
                app.TicknessText.Text="Radius";
                app.loc.Value=7;
                app.loc.Limits=[0,app.XYMax.Value];
                app.loc_2.Visible='on';
                app.loc_2.Limits=[0,app.XYMax.Value];
                app.loc_2.Value=7;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.Tumor_GrowthUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {685, 685, 685};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {685, 685};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x', 458};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Tumor_GrowthUIFigure = uifigure('Visible', 'off');
            app.Tumor_GrowthUIFigure.Name = 'Tumor_Growth';
            app.Tumor_GrowthUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'app.png');
            app.Tumor_GrowthUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.Tumor_GrowthUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulationSettingsLabel = uilabel(app.LeftPanel);
            app.SimulationSettingsLabel.Text = 'Simulation Settings';
            app.BoxDimensionLabel = uilabel(app.LeftPanel);
            app.BoxDimensionLabel.Text = 'Box Dimension';
            app.BoxDimension = uieditfield(app.LeftPanel, 'numeric');
            app.BoxDimension.Limits = [1 Inf];
            app.BoxDimension.Value = 50;
            app.EndTimemonthLabel = uilabel(app.LeftPanel);
            app.EndTimemonthLabel.Text = 'End Time (months)';
            app.EndTime = uieditfield(app.LeftPanel, 'numeric');
            app.EndTime.Limits = [0 Inf];
            app.EndTime.Value = 36;
            app.TimeStepdaysLabel = uilabel(app.LeftPanel);
            app.TimeStepdaysLabel.Text = 'Time Step (days)';
            app.TimeStep = uieditfield(app.LeftPanel, 'numeric');
            app.TimeStep.Limits = [0 Inf];
            app.TimeStep.Value = 10;
            app.InitialTumorShapeDropDownLabel = uilabel(app.LeftPanel);
            app.InitialTumorShapeDropDownLabel.Text = 'Initial Tumor Shape';
            app.InitialTumorShapeDropDown = uidropdown(app.LeftPanel);
            app.InitialTumorShapeDropDown.Items = {'Spheric', 'Flowers', 'Random'};
            app.InitialTumorShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @InitialTumorShapeDropDownValueChanged, true);
            app.InitialTumorShapeDropDown.Value = 'Spheric';
            app.TimeStepdaysLabel_2 = uilabel(app.LeftPanel);
            app.TimeStepdaysLabel_2.Text = 'Initial Tumor Radius (cm)';
            app.InitialRadius = uieditfield(app.LeftPanel, 'numeric');
            app.InitialRadius.Limits = [0 Inf];
            app.InitialRadius.Value = 1.5;
            app.BoxDimensionLabel_2 = uilabel(app.LeftPanel);
            app.BoxDimensionLabel_2.Text = 'XY Maximum (cm)';
            app.XYMax = uieditfield(app.LeftPanel, 'numeric');
            app.XYMax.Limits = [14 Inf];
            app.XYMax.ValueChangedFcn = createCallbackFcn(app, @XYMaxValueChanged, true);
            app.XYMax.Value = 14;
            app.TimeStepdaysLabel_3 = uilabel(app.LeftPanel);
            app.TimeStepdaysLabel_3.Text = 'Nº Initial Tumoral Cells';
            app.InitialCells = uieditfield(app.LeftPanel, 'numeric');
            app.InitialCells.Limits = [0 Inf];
            app.InitialCells.Value = 100000;
            app.alfa = uieditfield(app.LeftPanel, 'numeric');
            app.alfa.Limits = [0 Inf];
            app.alfa.Visible = 'off';
            app.alfa.Value = 0.5;
            app.omega = uieditfield(app.LeftPanel, 'numeric');
            app.omega.Limits = [0 Inf];
            app.omega.Visible = 'off';
            app.omega.Value = 8;
            app.alfa_text = uilabel(app.LeftPanel);
            app.alfa_text.Visible = 'off';
            app.alfa_text.Text = 'α';
            app.omega_text = uilabel(app.LeftPanel);
            app.omega_text.Visible = 'off';
            app.omega_text.Text = 'ω';
            app.y1 = uieditfield(app.LeftPanel, 'numeric');
            app.y1.Limits = [0 Inf];
            app.y1.Visible = 'off';
            app.y1.Value = 1;
            app.y2 = uieditfield(app.LeftPanel, 'numeric');
            app.y2.Limits = [0 Inf];
            app.y2.Visible = 'off';
            app.y2.Value = 2;
            app.y3 = uieditfield(app.LeftPanel, 'numeric');
            app.y3.Limits = [0 Inf];
            app.y3.Visible = 'off';
            app.y3.Value = 0.5;
            app.lim1 = uieditfield(app.LeftPanel, 'numeric');
            app.lim1.Limits = [0 Inf];
            app.lim1.Visible = 'off';
            app.lim1.Value = 5000000;
            app.lim2 = uieditfield(app.LeftPanel, 'numeric');
            app.lim2.Limits = [0 Inf];
            app.lim2.Visible = 'off';
            app.lim2.Value = 10000000;
            app.y = uilabel(app.LeftPanel);
            app.y.Visible = 'off';
            app.y.Text = 'γ';
            app.lim = uilabel(app.LeftPanel);
            app.lim.Visible = 'off';
            app.lim.Text = 'Limits';
            app.VesselsContributionCheckBox = uicheckbox(app.LeftPanel);
            app.VesselsContributionCheckBox.ValueChangedFcn = createCallbackFcn(app, @VesselsContributionCheckBoxValueChanged, true);
            app.VesselsContributionCheckBox.Text = 'Vessels Contribution';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'info.png');
            app.Button.Text = '';
            app.FunctionShapeDropDown = uidropdown(app.LeftPanel);
            app.FunctionShapeDropDown.Items = {'Hyperbolic Tangent', 'Retangular'};
            app.FunctionShapeDropDown.Visible = 'off';
            app.FunctionShapeDropDown.Value = 'Hyperbolic Tangent';
            app.FunctionShapeText = uilabel(app.LeftPanel);
            app.FunctionShapeText.Visible = 'off';
            app.FunctionShapeText.Text = 'Function Shape';
            app.InfunctionofProliferationrateLabel = uilabel(app.LeftPanel);
            app.InfunctionofProliferationrateLabel.Text = 'In function of Proliferation rate ';
            app.VesselsContributionCheckBox_2 = uicheckbox(app.LeftPanel);
            app.VesselsContributionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VesselsContributionCheckBox_2ValueChanged, true);
            app.VesselsContributionCheckBox_2.Text = 'Vessels Contribution';
            app.InfunctionofLocalizationLabel = uilabel(app.LeftPanel);
            app.InfunctionofLocalizationLabel.Text = 'In function of Localization ';
            app.VesselShapeDropDown = uidropdown(app.LeftPanel);
            app.VesselShapeDropDown.Items = {'Horizontal Line', 'Vertical Line', 'Circle'};
            app.VesselShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @VesselShapeDropDownValueChanged, true);
            app.VesselShapeDropDown.Visible = 'off';
            app.VesselShapeDropDown.Value = 'Horizontal Line';
            app.VesselShapeText = uilabel(app.LeftPanel);
            app.VesselShapeText.Visible = 'off';
            app.VesselShapeText.Text = 'Vessel Shape';
            app.delta1 = uieditfield(app.LeftPanel, 'numeric');
            app.delta1.Limits = [0 Inf];
            app.delta1.Visible = 'off';
            app.delta1.Value = 0.5;
            app.delta2 = uieditfield(app.LeftPanel, 'numeric');
            app.delta2.Limits = [0 Inf];
            app.delta2.Visible = 'off';
            app.delta2.Value = 2;
            app.delta = uilabel(app.LeftPanel);
            app.delta.Visible = 'off';
            app.delta.Text = 'δ';
            app.loc = uieditfield(app.LeftPanel, 'numeric');
            app.loc.Limits = [0 Inf];
            app.loc.Visible = 'off';
            app.loc.Value = 7;
            app.LocText = uilabel(app.LeftPanel);
            app.LocText.Visible = 'off';
            app.LocText.Text = 'Y';
            app.tickness = uieditfield(app.LeftPanel, 'numeric');
            app.tickness.Limits = [0 Inf];
            app.tickness.Visible = 'off';
            app.tickness.Value = 1;
            app.TicknessText = uilabel(app.LeftPanel);
            app.TicknessText.Visible = 'off';
            app.TicknessText.Text = 'Tickness';
            app.OutsideLabel = uilabel(app.LeftPanel);
            app.OutsideLabel.Visible = 'off';
            app.OutsideLabel.Text = 'Outside';
            app.InsideLabel = uilabel(app.LeftPanel);
            app.InsideLabel.Visible = 'off';
            app.InsideLabel.Text = 'Inside';
            app.loc_2 = uieditfield(app.LeftPanel, 'numeric');
            app.loc_2.Limits = [0 Inf];
            app.loc_2.Visible = 'off';
            app.loc_2.Value = 7;
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.TumorGrowthTab = uitab(app.TabGroup);
            app.TumorGrowthTab.Title = 'Tumor Growth';
            app.graph1 = uiaxes(app.TumorGrowthTab);
            xlabel(app.graph1, 'X (cm)')
            ylabel(app.graph1, 'Y (cm)')
            app.graph1.XTick = [1 51];
            app.graph1.XTickLabel = {'0'; '14'};
            app.graph1.YTick = [1 51];
            app.graph1.YTickLabel = {'0'; '14'};
            app.graph1.SubtitleFontWeight = 'bold';
            app.ProliferationStateTab = uitab(app.TabGroup);
            app.ProliferationStateTab.Title = 'Proliferation State';
            app.graph2 = uiaxes(app.ProliferationStateTab);
            xlabel(app.graph2, 'X (cm)')
            ylabel(app.graph2, 'Y (cm)')
            app.graph2.XTick = [1 51];
            app.graph2.XTickLabel = {'0'; '14'};
            app.graph2.YTick = [1 51];
            app.graph2.YTickLabel = {'0'; '14'};
            app.graph2.SubtitleFontWeight = 'bold';
            app.SpacialVesselContribuitionTab = uitab(app.TabGroup);
            app.SpacialVesselContribuitionTab.Title = 'Spacial Vessel Contribuition';
            app.graph7 = uiaxes(app.SpacialVesselContribuitionTab);
            xlabel(app.graph7, 'X (cm)')
            ylabel(app.graph7, 'Y (cm)')
            app.graph7.XTick = [1 51];
            app.graph7.XTickLabel = {'0'; '14'};
            app.graph7.YTick = [1 51];
            app.graph7.YTickLabel = {'0'; '14'};
            app.graph7.SubtitleFontWeight = 'bold';
            app.MetabolicRadiusTab = uitab(app.TabGroup);
            app.MetabolicRadiusTab.Title = 'Metabolic Radius';
            app.graph5 = uiaxes(app.MetabolicRadiusTab);
            xlabel(app.graph5, 'Time (months)')
            ylabel(app.graph5, 'HOC (cm)')
            app.graph5.SubtitleFontWeight = 'bold';
            app.ProliferationAverageTab = uitab(app.TabGroup);
            app.ProliferationAverageTab.Title = 'Proliferation Average';
            app.graph6 = uiaxes(app.ProliferationAverageTab);
            xlabel(app.graph6, 'Time (months)')
            ylabel(app.graph6, 'HOC (cm)')
            app.graph6.SubtitleFontWeight = 'bold';
            app.HOCTab = uitab(app.TabGroup);
            app.HOCTab.Title = 'HOC';
            app.graph3 = uiaxes(app.HOCTab);
            xlabel(app.graph3, 'Time (months)')
            ylabel(app.graph3, 'HOC (cm)')
            app.graph3.SubtitleFontWeight = 'bold';
            app.NHOCTab = uitab(app.TabGroup);
            app.NHOCTab.Title = 'NHOC';
            app.graph4 = uiaxes(app.NHOCTab);
            xlabel(app.graph4, 'Time (months)')
            ylabel(app.graph4, 'HOC (cm)')
            app.graph4.SubtitleFontWeight = 'bold';
            app.PlayButton = uibutton(app.CenterPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'images', 'play.png');
            app.PlayButton.Text = 'Play';
            app.RightPanel = uipanel(app.GridLayout);
            app.DifferentialEquationConstantsLabel = uilabel(app.RightPanel);
            app.DifferentialEquationConstantsLabel.Text = 'Differential Equation Constants';
            app.Image1 = uiimage(app.RightPanel);
            app.Image1.ImageSource = fullfile(pathToMLAPP, 'images', 'eq1.png');
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'eq2.png');
            app.DcSliderLabel = uilabel(app.RightPanel);
            app.DcSliderLabel.Text = 'Dc';
            app.DcSlider = uislider(app.RightPanel);
            app.DcSlider.Limits = [0 0.0005];
            app.DcSlider.MajorTicks = [0 0.00035 0.0005];
            app.DcSlider.MajorTickLabels = {'0', '3.5e-4', '5e-4'};
            app.DcSlider.ValueChangingFcn = createCallbackFcn(app, @DcSliderValueChanging, true);
            app.DcSlider.MinorTicks = [];
            app.DcSlider.Value = 0.00035;
            app.DpSliderLabel = uilabel(app.RightPanel);
            app.DpSliderLabel.Text = 'Dp';
            app.DpSlider = uislider(app.RightPanel);
            app.DpSlider.Limits = [0 2e-08];
            app.DpSlider.MajorTicks = [0 1.3e-08 2e-08];
            app.DpSlider.MajorTickLabels = {'0', '1.3e-8', '2e-8'};
            app.DpSlider.ValueChangingFcn = createCallbackFcn(app, @DpSliderValueChanging, true);
            app.DpSlider.MinorTicks = [];
            app.DpSlider.Value = 1.3e-08;
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'µ';
            app.miuSlider = uislider(app.RightPanel);
            app.miuSlider.Limits = [0 0.01];
            app.miuSlider.MajorTicks = [0 0.004 0.01];
            app.miuSlider.MajorTickLabels = {'0', '4e-3', '1e-02'};
            app.miuSlider.ValueChangingFcn = createCallbackFcn(app, @miuSliderValueChanging, true);
            app.miuSlider.MinorTicks = [];
            app.miuSlider.Value = 0.004;
            app.KSliderLabel = uilabel(app.RightPanel);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.RightPanel);
            app.KSlider.Limits = [10000000 100000000];
            app.KSlider.MajorTicks = [10000000 80000000 100000000];
            app.KSlider.MajorTickLabels = {'1e+7', '8e+7', '1e+8'};
            app.KSlider.ValueChangingFcn = createCallbackFcn(app, @KSliderValueChanging, true);
            app.KSlider.MinorTicks = [];
            app.KSlider.Value = 80000000;
            app.mSliderLabel = uilabel(app.RightPanel);
            app.mSliderLabel.Text = 'ρm';
            app.pmaxSlider = uislider(app.RightPanel);
            app.pmaxSlider.Limits = [0.04 0.1];
            app.pmaxSlider.MajorTicks = [0.04 0.06 0.1];
            app.pmaxSlider.MajorTickLabels = {'0.04', '0.06', '0.1'};
            app.pmaxSlider.ValueChangingFcn = createCallbackFcn(app, @pmaxSliderValueChanging, true);
            app.pmaxSlider.MinorTicks = [];
            app.pmaxSlider.Value = 0.06;
            app.DcText = uieditfield(app.RightPanel, 'numeric');
            app.DcText.Limits = [0 0.0005];
            app.DcText.ValueChangedFcn = createCallbackFcn(app, @DcTextValueChanged, true);
            app.DcText.Value = 0.00035;
            app.DpText = uieditfield(app.RightPanel, 'numeric');
            app.DpText.Limits = [0 2e-08];
            app.DpText.ValueChangedFcn = createCallbackFcn(app, @DpTextValueChanged, true);
            app.DpText.Value = 1.3e-08;
            app.miuText = uieditfield(app.RightPanel, 'numeric');
            app.miuText.Limits = [0 0.01];
            app.miuText.ValueChangedFcn = createCallbackFcn(app, @miuTextValueChanged, true);
            app.miuText.Value = 0.004;
            app.KText = uieditfield(app.RightPanel, 'numeric');
            app.KText.Limits = [10000000 100000000];
            app.KText.ValueChangedFcn = createCallbackFcn(app, @KTextValueChanged, true);
            app.KText.Value = 80000000;
            app.pmaxText = uieditfield(app.RightPanel, 'numeric');
            app.pmaxText.Limits = [0.01 0.1];
            app.pmaxText.ValueChangedFcn = createCallbackFcn(app, @pmaxTextValueChanged, true);
            app.pmaxText.Value = 0.06;
            app.cm2dayLabel = uilabel(app.RightPanel);
            app.cm2dayLabel.Text = 'cm^2/day';
            app.day3Label = uilabel(app.RightPanel);
            app.day3Label.Text = '/day^3';
            app.dayLabel = uilabel(app.RightPanel);
            app.dayLabel.Text = '/day';
            app.cellscm3Label = uilabel(app.RightPanel);
            app.cellscm3Label.Text = 'cells/cm^3';
            app.dayLabel_2 = uilabel(app.RightPanel);
            app.dayLabel_2.Text = '/day';
            app.SimulationResultsLabel = uilabel(app.RightPanel);
            app.SimulationResultsLabel.Text = 'Simulation Results';
            app.Results = uitextarea(app.RightPanel);
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'app.png');
            % Show the figure after all components are created
            app.Tumor_GrowthUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tumor_growth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tumor_GrowthUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tumor_GrowthUIFigure)
        end
    end
end